classdef Tumor_detection_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ANovelMethodofClassificationofTumorusingCNNUIFigure  matlab.ui.Figure
        usermanual                      matlab.ui.container.Panel
        Label_5                         matlab.ui.control.Label
        Label_4                         matlab.ui.control.Label
        Label_3                         matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        HomeButton                      matlab.ui.control.StateButton
        TumorSpotButton                 matlab.ui.control.StateButton
        TumorTypeButton                 matlab.ui.control.StateButton
        BenignorMalignantButton         matlab.ui.control.StateButton
        ResultPanel                     matlab.ui.container.Panel
        Output_box                      matlab.ui.control.EditField
        ThegivenMRIscanconsistsofLabel  matlab.ui.control.Label
        MRIscanimagegivenLabel          matlab.ui.control.Label
        Input_img                       matlab.ui.control.Image
        ImageButton                     matlab.ui.control.Button
        ClickontheimagebuttonandselecttheMRIimageoftheBrainLabel  matlab.ui.control.Label
        tumorspot_axes                  matlab.ui.control.UIAxes
        TumorDetectionandClassificationLabel  matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: BenignorMalignantButton
        function BenignorMalignantButtonValueChanged(app, event)
            value = app.BenignorMalignantButton.Value;
            cla(app.tumorspot_axes);
            app.usermanual.Visible='off';
            white_screen=ones([256 256 3]);
            app.Input_img.ImageSource=white_screen;
            app.tumorspot_axes.Visible='off';
            app.Output_box.Value=' ';
            if strcmp(app.ResultPanel.Visible,'off')==1 && strcmp(app.ResultPanel.Enable,'off')==1
                app.ResultPanel.Visible='on';
                app.ResultPanel.Enable='on';
            end
            app.ResultPanel.Title='Benign Or Malignant';
           

        end

        % Value changed function: TumorTypeButton
        function TumorTypeButtonValueChanged(app, event)
            value = app.TumorTypeButton.Value;
            cla(app.tumorspot_axes);
            app.usermanual.Visible='off';
            white_screen=ones([256 256 3]);
            app.Input_img.ImageSource=white_screen;
            app.Output_box.Value=' ';
            app.tumorspot_axes.Visible='off';
            app.Output_box.Value=' ';
            if strcmp(app.ResultPanel.Visible,'off')==1 && strcmp(app.ResultPanel.Enable,'off')==1
                app.ResultPanel.Visible='on';
                app.ResultPanel.Enable='on';
            end
            app.ResultPanel.Title='Tumor Type';
            
        end

        % Value changed function: TumorSpotButton
        function TumorSpotButtonValueChanged(app, event)
            value = app.TumorSpotButton.Value;
            white_screen=ones([256 256 3]);
            app.Input_img.ImageSource=white_screen;
            cla(app.tumorspot_axes);
            app.usermanual.Visible='off';
            app.Output_box.Visible='off';
            app.Output_box.Enable='off';
            if strcmp(app.ResultPanel.Visible,'off')==1 && strcmp(app.ResultPanel.Enable,'off')==1
               app.ResultPanel.Visible='on';
               app.ResultPanel.Enable='on';
            end
            app.ResultPanel.Title='Tumor Spot';
        end

        % Button pushed function: ImageButton
        function ImageButtonPushed(app, event)
            disp(app.ResultPanel.Title);
            app.Output_box.Value=' ';
            white_screen=ones([256 256 3]);
            app.Input_img.ImageSource=white_screen;
            [file,path]=uigetfile('*.*',"MRI input image");
            if isequal(file,0)
            else
               MRI_scan=imread(fullfile(path,file));
                app.Input_img.ImageSource=MRI_scan;
                if strcmp(app.ResultPanel.Title,'Benign Or Malignant')==1
                    app.Output_box.Visible='on';
                    app.Output_box.Enable='on';
                    app.Output_box.Value='Processing.........';
                    img2 = im2bw(MRI_scan);
                    signal1 = img2(:,:);
                    [cA1,cH1,cV1,cD1] = dwt2(signal1,'db4');
                    [cA2,cH2,cV2,cD2] = dwt2(cA1,'db4');
                    [cA3,cH3,cV3,cD3] = dwt2(cA2,'db4');
                    DWT_feat = [cA3,cH3,cV3,cD3];
                    G = pca(DWT_feat);
                    g = graycomatrix(G);
                    stats = graycoprops(g,'Contrast Correlation Energy Homogeneity');
                    Contrast = stats.Contrast;
                    Correlation = stats.Correlation;
                    Energy = stats.Energy;
                    Homogeneity = stats.Homogeneity;
                    Mean = mean2(G);
                    Standard_Deviation = std2(G);
                    Entropy = entropy(G);
                    RMS = mean2(rms(G));
                    %Skewness = skewness(img)
                    Variance = mean2(var(double(G)));
                    a = sum(double(G(:)));
                    Smoothness = 1-(1/(1+a));
                    Kurtosis = kurtosis(double(G(:)));
                    Skewness = skewness(double(G(:)));
                    % Inverse Difference Movement
                    m = size(G,1);
                    n = size(G,2);
                    in_diff = 0;
                    for i = 1:m
                        for j = 1:n
                            temp = G(i,j)./(1+(i-j).^2);
                            in_diff = in_diff+temp;
                        end
                    end
                    IDM = double(in_diff);    
                    feat = [Contrast,Correlation,Energy,Homogeneity, Mean, Standard_Deviation, Entropy, RMS, Variance, Smoothness, Kurtosis, Skewness, IDM];
                    
                    load 'Trained data'\Trainset.mat
                    xdata = meas;
                    group = label;
                    svmStruct1 = fitcsvm(xdata,group,'KernelFunction', 'linear');
                    species= predict(svmStruct1,feat);
                    
                    disp(species);
                     if strcmpi(species,'MALIGNANT')
                         app.Output_box.Value='Malignant Tumor';
                     else
                         app.Output_box.Value='Benign Tumor';
                     end
                     
                elseif strcmp(app.ResultPanel.Title,'Tumor Type')==1
                    pause(1);
                    app.Output_box.Visible='on';
                    app.Output_box.Enable='on';
                    app.Output_box.Value='Processing.........';
                    load 'Trained data'\cnn87.mat
                    MRI_scan=imresize(MRI_scan,[224,224]);
                    [label,~]=classify(cnn87,MRI_scan);
                    pause(1);
                    label=string(label);
                    if strcmp(label,'glioma_tumor')==1
                        disp(label);
                        label='Glioma Tumor';
                        app.Output_box.Value=label;
                    elseif strcmp(label,'meningioma_tumor')==1
                        disp(label);
                        label=' Meningioma Tumor';
                        app.Output_box.Value=label;
                    elseif strcmp(label,'no_tumor')==1
                        disp(label);
                        label='No Tumor';
                        app.Output_box.Value=label;
                    elseif strcmp(label,'pituitary_tumor')==1
                        disp(label);
                        label='Pituitary Tumor'
                        app.Output_box.Value=label
                    end
                    
                    disp('ended');
                elseif strcmp(app.ResultPanel.Title,'Tumor Spot')==1
                    cla(app.tumorspot_axes);
                    pause(1);
                    I=MRI_scan;
                    gray = rgb2gray(I);
                    img = im2bw(gray,.6);
                    img = bwareaopen(img,80); 
                    TUMOr_spot_axes_display=app.tumorspot_axes;
                    imshow(img,'Parent',TUMOr_spot_axes_display);
    
                end 
            end
            
        end

        % Value changed function: HomeButton
        function HomeButtonValueChanged(app, event)
            value = app.HomeButton.Value;
            app.usermanual.Visible='on';
            app.tumorspot_axes.Visible='off';
            cla(app.tumorspot_axes,"reset");
            app.Output_box.Visible='off';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ANovelMethodofClassificationofTumorusingCNNUIFigure = uifigure('Visible', 'off');
            app.ANovelMethodofClassificationofTumorusingCNNUIFigure.Name = 'A Novel Method of Classification of Tumor using CNN';
            app.TumorDetectionandClassificationLabel = uilabel(app.ANovelMethodofClassificationofTumorusingCNNUIFigure);
            app.TumorDetectionandClassificationLabel.Text = 'Tumor Detection and Classification';
            app.ResultPanel = uipanel(app.ANovelMethodofClassificationofTumorusingCNNUIFigure);
            app.ResultPanel.Enable = 'off';
            app.ResultPanel.Visible = 'off';
            app.tumorspot_axes = uiaxes(app.ResultPanel);
            app.tumorspot_axes.XTick = [];
            app.tumorspot_axes.YTick = [];
            app.tumorspot_axes.Visible = 'off';
            app.ClickontheimagebuttonandselecttheMRIimageoftheBrainLabel = uilabel(app.ResultPanel);
            app.ClickontheimagebuttonandselecttheMRIimageoftheBrainLabel.Text = 'Click on the image button and select the MRI image of the Brain:';
            app.ImageButton = uibutton(app.ResultPanel, 'push');
            app.ImageButton.ButtonPushedFcn = createCallbackFcn(app, @ImageButtonPushed, true);
            app.ImageButton.Text = 'Image';
            app.Input_img = uiimage(app.ResultPanel);
            app.MRIscanimagegivenLabel = uilabel(app.ResultPanel);
            app.MRIscanimagegivenLabel.Text = 'MRI scan image given';
            app.ThegivenMRIscanconsistsofLabel = uilabel(app.ResultPanel);
            app.ThegivenMRIscanconsistsofLabel.Enable = 'off';
            app.ThegivenMRIscanconsistsofLabel.Visible = 'off';
            app.ThegivenMRIscanconsistsofLabel.Text = 'The given MRI scan consists of: ';
            app.Output_box = uieditfield(app.ResultPanel, 'text');
            app.Output_box.Editable = 'off';
            app.Output_box.Enable = 'off';
            app.Output_box.Visible = 'off';
            app.BenignorMalignantButton = uibutton(app.ANovelMethodofClassificationofTumorusingCNNUIFigure, 'state');
            app.BenignorMalignantButton.ValueChangedFcn = createCallbackFcn(app, @BenignorMalignantButtonValueChanged, true);
            app.BenignorMalignantButton.Text = 'Benign or Malignant';
            app.TumorTypeButton = uibutton(app.ANovelMethodofClassificationofTumorusingCNNUIFigure, 'state');
            app.TumorTypeButton.ValueChangedFcn = createCallbackFcn(app, @TumorTypeButtonValueChanged, true);
            app.TumorTypeButton.Text = 'Tumor Type';
            app.TumorSpotButton = uibutton(app.ANovelMethodofClassificationofTumorusingCNNUIFigure, 'state');
            app.TumorSpotButton.ValueChangedFcn = createCallbackFcn(app, @TumorSpotButtonValueChanged, true);
            app.TumorSpotButton.Text = 'Tumor Spot';
            app.HomeButton = uibutton(app.ANovelMethodofClassificationofTumorusingCNNUIFigure, 'state');
            app.HomeButton.ValueChangedFcn = createCallbackFcn(app, @HomeButtonValueChanged, true);
            app.HomeButton.Text = 'Home';
            app.usermanual = uipanel(app.ANovelMethodofClassificationofTumorusingCNNUIFigure);
            app.Label = uilabel(app.usermanual);
            app.Label.Text = 'To check the tumor in the MRI scan is of type Benign or Malignant that is Benign is taken as Non-Cancerous and Malignant is  ';
            app.Label_2 = uilabel(app.usermanual);
            app.Label_2.Text = 'Cancerous, click the Benign or Malignant Button';
            app.Label_3 = uilabel(app.usermanual);
            app.Label_3.Text = 'To check the tumor in the MRI scan is of which type of tumor, this version is providing this classification with an 87.87% and we are  ';
            app.Label_4 = uilabel(app.usermanual);
            app.Label_4.Text = 'classifying between four types they are Glioma, Pituitary, Meningioma and No Tumor. For this Feature click on the Tumor Type Button';
            app.Label_5 = uilabel(app.usermanual);
            app.Label_5.Text = {'To get the Tumot Spot click on the Tumor Spot button this feature help you in some extent to highliht the tumor spot                               '; '                                                                                                                                                                                                                 '; 'Click on the image button after the pop up and select the MRI scan u want to process.                                         '};
            % Show the figure after all components are created
            app.ANovelMethodofClassificationofTumorusingCNNUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Tumor_detection_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ANovelMethodofClassificationofTumorusingCNNUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ANovelMethodofClassificationofTumorusingCNNUIFigure)
        end
    end
end